classdef correctionGUI_EZView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FrameInfoLabel     matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        ffwd               matlab.ui.control.Button
        bback              matlab.ui.control.Button
        fwd                matlab.ui.control.Button
        back               matlab.ui.control.Button
        ReturntoGUIButton  matlab.ui.control.Button
        LeftPanel          matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        RWingPitchAxes     matlab.ui.control.UIAxes
        LWingPitchAxes     matlab.ui.control.UIAxes
        RWingElevAxes      matlab.ui.control.UIAxes
        LWingElevAxes      matlab.ui.control.UIAxes
        rollAxes           matlab.ui.control.UIAxes
        strokeAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main GUI App
        CallingAppHandles %handles from Main GUI App

        %properties for plotting:
        scale
        Lstub
        Lbar

        bodyChangeFlag
        rightWingChangeFlag
        leftWingChangeFlag

        AhatChangeFlag
        stublineChangeFlag
        nrChangeFlag
        phChangeFlag

        spanRightChangeFlag
        chordRightChangeFlag
        spanLeftChangeFlag
        chordLeftChangeFlag

        marker_size

        frameCurr
        tmsCurr

        Nimages

        %data properties
        tvec
        frameStartInd
        frameEndInd
        res
        RESIDX
        bodyInd
        rightWingInd
        leftWingInd
        bodyCM
        rollHats
        normRolls
        psiHats
        rightWingCM
        leftWingCM
        span1Hats
        span2Hats
        chord1Hats
        chord2Hats
        

        %properties specific to top view (stroke angle) plot
        hBody1
        hRightWing1
        hLeftWing1
        hAhat1
        hStubline1
        hNr1
        hPh1
        hSpanRight1
        hChordRight1
        hSpanLeft1
        hChordLeft1

        %properties specific to pitch axis view (roll angle) plot
        hBody2
        hRightWing2
        hLeftWing2
        hAhat2
        hStubline2
        hNr2
        hPh2
        hSpanRight2
        hChordRight2
        hSpanLeft2
        hChordLeft2

        %properties specific to left side view (left wing elevation) plot
        hBody3
        hRightWing3
        hLeftWing3
        hAhat3
        hStubline3
        hNr3
        hPh3
        hSpanRight3
        hChordRight3
        hSpanLeft3
        hChordLeft3

        %properties specific to left side view (left wing elevation) plot
        hBody4
        hRightWing4
        hLeftWing4
        hAhat4
        hStubline4
        hNr4
        hPh4
        hSpanRight4
        hChordRight4
        hSpanLeft4
        hChordLeft4

        %properties specific to left span axis view (left wing pitch) plot
        hBody5
        hRightWing5
        hLeftWing5
        hAhat5
        hStubline5
        hNr5
        hPh5
        hSpanRight5
        hChordRight5
        hSpanLeft5
        hChordLeft5

        %properties specific to right span axis view (right wing pitch) plot
        hBody6
        hRightWing6
        hLeftWing6
        hAhat6
        hStubline6
        hNr6
        hPh6
        hSpanRight6
        hChordRight6
        hSpanLeft6
        hChordLeft6
    end
    
    methods (Access = private)
       
        function initPlots(app)
            % ----------------------------------
            % initialize plot handles
            
            % initialize handle fields for things to plot
            
            baseScale = 4 ; % 2; % 4
            app.scale = baseScale ;
            app.Lstub = 3.0*baseScale ;
            app.Lbar  = 10*baseScale ;
            
            %handles.ignoreFrames = [] ;
            
            % flags to mark whether or not things need to be updated
            app.bodyChangeFlag = true ;
            app.rightWingChangeFlag = true ;
            app.leftWingChangeFlag = true ;
            
            app.AhatChangeFlag = true ;
            app.stublineChangeFlag = true ;
            app.nrChangeFlag = true ;
            app.phChangeFlag = true ;
            
            app.spanRightChangeFlag = true ;
            app.chordRightChangeFlag = true ;
            app.spanLeftChangeFlag = true ;
            app.chordLeftChangeFlag = true ;

            %initialize plot elements for top view (stroke angle) plot
            hold(app.strokeAxes, 'on')
            app.hBody1 = plot3(app.strokeAxes, NaN, NaN, NaN, 'g.',...
                'MarkerSize',app.marker_size) ;
            app.hRightWing1 = plot3(app.strokeAxes, NaN, NaN, NaN, 'r.',...
                'MarkerSize',app.marker_size) ;
            app.hLeftWing1 = plot3(app.strokeAxes, NaN, NaN, NaN, 'b.',...
                'MarkerSize',app.marker_size) ;

            app.hAhat1 = line(app.strokeAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);
            app.hStubline1 = line(app.strokeAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hNr1 = line(app.strokeAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hPh1 = line(app.strokeAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);

            app.hSpanRight1 = line(app.strokeAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordRight1 = line(app.strokeAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','b','LineWidth',4);
            app.hSpanLeft1 = line(app.strokeAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordLeft1 = line(app.strokeAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',4);

            hold(app.strokeAxes, 'off')
            box(app.strokeAxes, 'on')
            grid(app.strokeAxes, 'on')
            axis(app.strokeAxes, 'equal')
            rotate3d on
            
            %initialize view
            azview1 = atan2(app.rollHats(app.frameCurr,2),app.rollHats(app.frameCurr,1))*180/pi-90 ;
            elview1 = 90 ;
            view(app.strokeAxes,azview1,elview1) ;

            %initialize elements for view down the pitch axis (roll angle)

            hold(app.rollAxes, 'on')
            app.hBody2 = plot3(app.rollAxes, NaN, NaN, NaN, 'g.',...
                'MarkerSize',app.marker_size) ;
            app.hRightWing2 = plot3(app.rollAxes, NaN, NaN, NaN, 'r.',...
                'MarkerSize',app.marker_size) ;
            app.hLeftWing2 = plot3(app.rollAxes, NaN, NaN, NaN, 'b.',...
                'MarkerSize',app.marker_size) ;

            app.hAhat2 = line(app.rollAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);
            app.hStubline2 = line(app.rollAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hNr2 = line(app.rollAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hPh2 = line(app.rollAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);

            app.hSpanRight2 = line(app.rollAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordRight2 = line(app.rollAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','b','LineWidth',4);
            app.hSpanLeft2 = line(app.rollAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordLeft2 = line(app.rollAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',4);

            hold(app.rollAxes, 'off')
            box(app.rollAxes, 'on')
            grid(app.rollAxes, 'on')
            axis(app.rollAxes, 'equal')
            rotate3d on
            
            %initialize view
            view(app.rollAxes,app.rollHats(app.frameCurr,:)) ;

            %initialize elements for left side view (left wing elevation angle)

            hold(app.LWingElevAxes, 'on')
            app.hBody3 = plot3(app.LWingElevAxes, NaN, NaN, NaN, 'g.',...
                'MarkerSize',app.marker_size) ;
            app.hRightWing3 = plot3(app.LWingElevAxes, NaN, NaN, NaN, 'r.',...
                'MarkerSize',app.marker_size) ;
            app.hLeftWing3 = plot3(app.LWingElevAxes, NaN, NaN, NaN, 'b.',...
                'MarkerSize',app.marker_size) ;

            app.hAhat3 = line(app.LWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);
            app.hStubline3 = line(app.LWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hNr3 = line(app.LWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hPh3 = line(app.LWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);

            app.hSpanRight3 = line(app.LWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordRight3 = line(app.LWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','b','LineWidth',4);
            app.hSpanLeft3 = line(app.LWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordLeft3 = line(app.LWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',4);

            hold(app.LWingElevAxes, 'off')
            box(app.LWingElevAxes, 'on')
            grid(app.LWingElevAxes, 'on')
            axis(app.LWingElevAxes, 'equal')
            rotate3d on
            
            %initialize view
            wingBodyProduct3 = dot(app.rollHats(app.frameCurr,1:2),app.span2Hats(app.frameCurr,1:2));
            if wingBodyProduct3 > 0
                azview3 = atan2(app.span2Hats(app.frameCurr,2),app.span2Hats(app.frameCurr,1))*180/pi+180;
            else
                azview3 = atan2(app.span2Hats(app.frameCurr,2),app.span2Hats(app.frameCurr,1))*180/pi;
            end

            elview3 = 0;
            view(app.LWingElevAxes,azview3,elview3);

            %initialize elements for right side view (right wing elevation angle)

            hold(app.RWingElevAxes, 'on')
            app.hBody4 = plot3(app.RWingElevAxes, NaN, NaN, NaN, 'g.',...
                'MarkerSize',app.marker_size) ;
            app.hRightWing4 = plot3(app.RWingElevAxes, NaN, NaN, NaN, 'r.',...
                'MarkerSize',app.marker_size) ;
            app.hLeftWing4 = plot3(app.RWingElevAxes, NaN, NaN, NaN, 'b.',...
                'MarkerSize',app.marker_size) ;

            app.hAhat4 = line(app.RWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);
            app.hStubline4 = line(app.RWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hNr4 = line(app.RWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hPh4 = line(app.RWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);

            app.hSpanRight4 = line(app.RWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordRight4 = line(app.RWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','b','LineWidth',4);
            app.hSpanLeft4 = line(app.RWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordLeft4 = line(app.RWingElevAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',4);

            hold(app.RWingElevAxes, 'off')
            box(app.RWingElevAxes, 'on')
            grid(app.RWingElevAxes, 'on')
            axis(app.RWingElevAxes, 'equal')
            rotate3d on
            
            %initialize view
            wingBodyProduct4 = dot(app.rollHats(app.frameCurr,1:2),app.span1Hats(app.frameCurr,1:2));
            if wingBodyProduct4 > 0
                azview4 = atan2(app.span1Hats(app.frameCurr,2),app.span1Hats(app.frameCurr,1))*180/pi;
            else
                azview4 = atan2(app.span1Hats(app.frameCurr,2),app.span1Hats(app.frameCurr,1))*180/pi+180;
            end

            elview4 = 0;
            view(app.RWingElevAxes,azview4,elview4);


            %initialize elements for view down the left wing span vector (left wing pitch)

            hold(app.LWingPitchAxes, 'on')
            app.hBody5 = plot3(app.LWingPitchAxes, NaN, NaN, NaN, 'g.',...
                'MarkerSize',app.marker_size) ;
            app.hRightWing5 = plot3(app.LWingPitchAxes, NaN, NaN, NaN, 'r.',...
                'MarkerSize',app.marker_size) ;
            app.hLeftWing5 = plot3(app.LWingPitchAxes, NaN, NaN, NaN, 'b.',...
                'MarkerSize',app.marker_size) ;

            app.hAhat5 = line(app.LWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);
            app.hStubline5 = line(app.LWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hNr5 = line(app.LWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hPh5 = line(app.LWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);

            app.hSpanRight5 = line(app.LWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordRight5 = line(app.LWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','b','LineWidth',4);
            app.hSpanLeft5 = line(app.LWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordLeft5 = line(app.LWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',4);

            hold(app.LWingPitchAxes, 'off')
            box(app.LWingPitchAxes, 'on')
            grid(app.LWingPitchAxes, 'on')
            axis(app.LWingPitchAxes, 'equal')
            rotate3d on
            
            %initialize view
            view(app.LWingPitchAxes,app.span2Hats(app.frameCurr,:)) ;

             %initialize elements for view down the right wing span vector (right wing pitch)

            hold(app.RWingPitchAxes, 'on')
            app.hBody6 = plot3(app.RWingPitchAxes, NaN, NaN, NaN, 'g.',...
                'MarkerSize',app.marker_size) ;
            app.hRightWing6 = plot3(app.RWingPitchAxes, NaN, NaN, NaN, 'r.',...
                'MarkerSize',app.marker_size) ;
            app.hLeftWing6 = plot3(app.RWingPitchAxes, NaN, NaN, NaN, 'b.',...
                'MarkerSize',app.marker_size) ;

            app.hAhat6 = line(app.RWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);
            app.hStubline6 = line(app.RWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hNr6 = line(app.RWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',8);
            app.hPh6 = line(app.RWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',8);

            app.hSpanRight6 = line(app.RWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordRight6 = line(app.RWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','b','LineWidth',4);
            app.hSpanLeft6 = line(app.RWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','k','LineWidth',4);
            app.hChordLeft6 = line(app.RWingPitchAxes,[NaN, NaN],[NaN, NaN],[NaN, NaN],...
                'Color','r','LineWidth',4);

            hold(app.RWingPitchAxes, 'off')
            box(app.RWingPitchAxes, 'on')
            grid(app.RWingPitchAxes, 'on')
            axis(app.RWingPitchAxes, 'equal')
            rotate3d on
            
            %initialize view
            view(app.RWingPitchAxes,app.span1Hats(app.frameCurr,:)) ;


        end

        function updateDisplay(app)
            %
            %============================
            % get voxel info
            %============================
            row1 = app.frameStartInd(app.frameCurr) ;
            row2 = app.frameEndInd(app.frameCurr) ;
            coords = app.res(row1:row2,2:4) ; % xyz
            IDX = app.RESIDX(row1:row2,:) ;  % (isbody, isrightwing, isleftwing)
            bodyRows      = (IDX(:,app.bodyInd)==1) ;
            rightWingRows = (IDX(:,app.rightWingInd)==1) ;
            leftWingRows  = (IDX(:,app.leftWingInd)==1) ;
            
            %scale = app.scale ; % constant
            %Lbar = app.Lbar ; % constant

            %============================
            % get body vector info
            %============================
            cb = app.bodyCM(app.frameCurr,:) ; % body cm
            rollHat = app.rollHats(app.frameCurr,:) ; % body axis unit vector
            
            checksum = sum(app.normRolls(app.frameCurr,:));
            if ((~isfinite(checksum) || norm(app.normRolls(app.frameCurr,:))==0) && (app.frameCurr>1))
                app.normRolls(app.frameCurr,:) = app.normRolls(app.frameCurr-1,:) ;
            
                %disp('Using roll vector from previous frame') ;
            end
            
            % if still zero, start with zero roll
            if (norm(app.normRolls(app.frameCurr,:))==0)
                app.normRolls(app.frameCurr,:) = - cross( rollHat, [0 0 1]) ;
                %disp('starting from rho=0') ;
            end
            normRoll = app.normRolls(app.frameCurr,:) ;
            % make sure the roll vector is perpendicular to AHat  and renormalize
            normRoll = normRoll - rollHat * dot(normRoll, rollHat) ;
            normRoll = normRoll / norm(normRoll) ;
            app.normRolls(app.frameCurr,:) = normRoll ;
            
            stubvec  = app.Lstub * RotatePoint(normRoll,[0 0 0],rollHat,90) ;
            psiHat = app.psiHats(app.frameCurr,:) ;

            %============================
            % get wing vector info
            %============================
            cr = app.rightWingCM(app.frameCurr,:) ;
            cl = app.leftWingCM(app.frameCurr,:) ;
            
            span1Hat = app.span1Hats(app.frameCurr,:) ;
            span2Hat = app.span2Hats(app.frameCurr,:) ;
            chord1Hat = app.chord1Hats(app.frameCurr,:) ;
            chord2Hat = app.chord2Hats(app.frameCurr,:) ;
            %--------------------------------------------------------------------------

            %============================
            % voxel plotting
            %============================
            if app.bodyChangeFlag
                set([app.hBody1, app.hBody2,app.hBody3,app.hBody4,app.hBody5,app.hBody6], 'XData', coords(bodyRows,1),...
                    'YData', coords(bodyRows,2),...
                    'ZData', coords(bodyRows,3));
            end
            if app.rightWingChangeFlag
                set([app.hRightWing1,app.hRightWing2,app.hRightWing3,app.hRightWing4,app.hRightWing5,app.hRightWing6], 'XData', coords(rightWingRows,1),...
                    'YData', coords(rightWingRows,2),...
                    'ZData', coords(rightWingRows,3));
            end
            if app.leftWingChangeFlag
                set([app.hLeftWing1, app.hLeftWing2,app.hLeftWing3,app.hLeftWing4,app.hLeftWing5,app.hLeftWing6], 'XData', coords(leftWingRows,1),...
                    'YData', coords(leftWingRows,2),...
                    'ZData', coords(leftWingRows,3));
            end

            %============================
            % body vector plotting
            %============================
            if app.AhatChangeFlag
                x_data = [cb(1), cb(1)+ app.scale*15*rollHat(1)] ;
                y_data = [cb(2), cb(2)+ app.scale*15*rollHat(2)] ;
                z_data = [cb(3), cb(3)+ app.scale*15*rollHat(3)] ;
            
                set([app.hAhat1,app.hAhat2,app.hAhat3,app.hAhat4,app.hAhat5,app.hAhat6], 'XData', x_data,...
                    'YData', y_data,...
                    'ZData', z_data);
            end
            if app.stublineChangeFlag
            
                x_data = [cb(1), cb(1)+ stubvec(1)] ;
                y_data = [cb(2), cb(2)+ stubvec(2)] ;
                z_data = [cb(3), cb(3)+ stubvec(3)] ;
                set([app.hStubline1,app.hStubline2,app.hStubline3,app.hStubline5,app.hStubline6], 'XData', x_data,...
                    'YData', y_data,...
                    'ZData', z_data);
            end
            if app.nrChangeFlag
                x_data = [cb(1)+stubvec(1)-app.Lbar*normRoll(1), cb(1)+stubvec(1)+app.Lbar*normRoll(1)] ;
                y_data = [cb(2)+stubvec(2)-app.Lbar*normRoll(2), cb(2)+stubvec(2)+app.Lbar*normRoll(2)] ;
                z_data = [cb(3)+stubvec(3)-app.Lbar*normRoll(3), cb(3)+stubvec(3)+app.Lbar*normRoll(3)] ;
                set([app.hNr1,app.hNr2,app.hNr3,app.hNr4,app.hNr5,app.hNr6], 'XData', x_data,...
                    'YData', y_data,...
                    'ZData', z_data);
            end
            if app.phChangeFlag
                x_data = [cb(1),cb(1)+app.scale*7*psiHat(1)] ;
                y_data = [cb(2),cb(2)+app.scale*7*psiHat(2)] ;
                z_data = [cb(3),cb(3)+app.scale*7*psiHat(3)] ;
                set([app.hPh1,app.hPh2,app.hPh3,app.hPh4,app.hPh5,app.hNr6], 'XData', x_data,...
                    'YData', y_data,...
                    'ZData', z_data);
            end

            %============================
            % wing vector plotting
            %============================
            if app.spanRightChangeFlag
                x_data = [cr(1), cr(1)+ app.scale*10*span1Hat(1)] ;
                y_data = [cr(2), cr(2)+ app.scale*10*span1Hat(2)] ;
                z_data = [cr(3), cr(3)+ app.scale*10*span1Hat(3)] ;
            
                set([app.hSpanRight1,app.hSpanRight2,app.hSpanRight3,app.hSpanRight4,app.hSpanRight5,app.hSpanRight6], 'XData', x_data,...
                    'YData', y_data,...
                    'ZData', z_data);
            end
            if app.chordRightChangeFlag
                x_data = [cr(1), cr(1)+ app.scale*8*chord1Hat(1)] ;
                y_data = [cr(2), cr(2)+ app.scale*8*chord1Hat(2)] ;
                z_data = [cr(3), cr(3)+ app.scale*8*chord1Hat(3)] ;

                set([app.hChordRight1, app.hChordRight2,app.hChordRight3,app.hChordRight4,app.hChordRight5, app.hChordRight6], 'XData', x_data,...
                    'YData', y_data,...
                    'ZData', z_data);
            end
            if app.spanLeftChangeFlag
                x_data = [cl(1), cl(1)+ app.scale*10*span2Hat(1)] ;
                y_data = [cl(2), cl(2)+ app.scale*10*span2Hat(2)] ;
                z_data = [cl(3), cl(3)+ app.scale*10*span2Hat(3)] ;

                set([app.hSpanLeft1,app.hSpanLeft2,app.hSpanLeft3,app.hSpanLeft4,app.hSpanLeft5,app.hSpanLeft6], 'XData', x_data,...
                    'YData', y_data,...
                    'ZData', z_data);
            end
            if app.chordLeftChangeFlag
                x_data = [cl(1), cl(1)+ app.scale*8*chord2Hat(1)] ;
                y_data = [cl(2), cl(2)+ app.scale*8*chord2Hat(2)] ;
                z_data = [cl(3), cl(3)+ app.scale*8*chord2Hat(3)] ;
                set([app.hChordLeft1,app.hChordLeft2,app.hChordLeft3,app.hChordLeft4,app.hChordLeft5,app.hChordLeft6], 'XData', x_data,...
                    'YData', y_data,...
                    'ZData', z_data);
            end

            app.FrameInfoLabel.Text = ['t(ms)=' num2str(app.tmsCurr) '  Frame # ' ...
                num2str(app.frameCurr) ' of ' num2str(app.Nimages)];

            %========================
            % Update camera views
            %=========================
            
            view(app.rollAxes,app.rollHats(app.frameCurr,:)) ;

            wingBodyProduct3 = dot(app.rollHats(app.frameCurr,1:2),app.span2Hats(app.frameCurr,1:2));
            if wingBodyProduct3 > 0
                azview3 = atan2(app.span2Hats(app.frameCurr,2),app.span2Hats(app.frameCurr,1))*180/pi+180;
            else
                azview3 = atan2(app.span2Hats(app.frameCurr,2),app.span2Hats(app.frameCurr,1))*180/pi ;
            end

            elview3 = 0;
            view(app.LWingElevAxes,azview3,elview3);

            wingBodyProduct4 = dot(app.rollHats(app.frameCurr,1:2),app.span1Hats(app.frameCurr,1:2));
            if wingBodyProduct4 > 0
                azview4 = atan2(app.span1Hats(app.frameCurr,2),app.span1Hats(app.frameCurr,1))*180/pi;
            else
                azview4 = atan2(app.span1Hats(app.frameCurr,2),app.span1Hats(app.frameCurr,1))*180/pi+180;
            end

            elview4 = 0;
            view(app.RWingElevAxes,azview4,elview4);

            view(app.LWingPitchAxes,app.span2Hats(app.frameCurr,:)) ;

            view(app.RWingPitchAxes,app.span1Hats(app.frameCurr,:)) ;
            
        end

        function updateChangeFlags(app, updateType)
            % ---------------------------------------------------------------------
            % function to store whether or not a given fly objet was changed
            
            % flag all variables for update display (useful for frame shifts)
            switch updateType
                case 'all'
                    app.bodyChangeFlag = true ;
                    app.rightWingChangeFlag = true ;
                    app.leftWingChangeFlag = true ;
            
                    app.AhatChangeFlag = true ;
                    app.stublineChangeFlag = true ;
                    app.nrChangeFlag = true ;
                    app.phChangeFlag = true ;
            
                    app.spanRightChangeFlag = true ;
                    app.chordRightChangeFlag = true ;
                    app.spanLeftChangeFlag = true ;
                    app.chordLeftChangeFlag = true ;
                case 'bodyvec'
                    app.AhatChangeFlag = true ;
                    app.stublineChangeFlag = true ;
                    app.nrChangeFlag = true ;
                    app.phChangeFlag = true ;
                case 'rvec'
                    app.spanRightChangeFlag = true ;
                    app.chordRightChangeFlag = true ;
                case 'lvec'
                    app.spanLeftChangeFlag = true ;
                    app.chordLeftChangeFlag = true ;
                case 'rchord'
                    app.chordRightChangeFlag = true ;
                case 'lchord'
                    app.chordLeftChangeFlag = true ;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, inputHandles, frameCurr, tmsCurr)
            app.CallingApp = caller;
            app.CallingAppHandles = inputHandles;

            %get relevant info out of inputHandles
            app.marker_size = inputHandles.marker_size;
            
            try
                app.frameCurr = frameCurr ;
                app.tmsCurr = tmsCurr;
                app.tvec = inputHandles.tvec;
                %disp('error free verision running');
            catch
                
                app.tmsCurr = tmsCurr ;
                app.tvec = inputHandles.tvec ;
                [~, newframeCurr] = min(abs(app.tvec - app.tmsCurr)) ;
                app.frameCurr = newframeCurr ;
                app.tmsCurr = app.tvec(newframeCurr) ;
                %disp('error caught')
                
            end

            app.frameStartInd = inputHandles.frameStartInd;
            app.frameEndInd = inputHandles.frameEndInd;
            app.res = inputHandles.res;
            app.RESIDX = inputHandles.RESIDX;
            app.bodyInd = inputHandles.bodyInd;
            app.rightWingInd = inputHandles.rightWingInd;
            app.leftWingInd = inputHandles.leftWingInd;

            app.bodyCM = inputHandles.bodyCM;
            app.rollHats = inputHandles.rollHats;
            app.normRolls = inputHandles.normRolls;
            app.psiHats = inputHandles.psiHats;

            app.rightWingCM = inputHandles.rightWingCM;
            app.leftWingCM = inputHandles.leftWingCM;
            app.span1Hats = inputHandles.span1Hats;
            app.span2Hats = inputHandles.span2Hats;
            app.chord1Hats = inputHandles.chord1Hats;
            app.chord2Hats = inputHandles.chord2Hats;

            %app.FrameInfoLabel = inputHandles.frame_info;
            app.Nimages = inputHandles.Nimages;

            initPlots(app);

            % try to deal with zoom/keypress conflict
            %{
            app.hManager = uigetmodemanager(app.UIFigure);
            try
                set(app.hManager.WindowListenerHandles, 'Enable', 'off');  % HG1
            catch
                [app.hManager.WindowListenerHandles.Enabled] = deal(false);  % HG2
            end
            set(app.UIFigure, 'WindowKeyPressFcn',@UIFigureWindowKeyPress);
            set(app.UIFigure, 'KeyPressFcn', []);
            %}

            updateDisplay(app);
        end

        % Button pushed function: ReturntoGUIButton
        function ReturntoGUIButtonPushed(app, event)
            % Call main app's function to update time
            app.CallingAppHandles.frameCurr = app.frameCurr;
            app.CallingAppHandles.tmsCurr = app.tmsCurr;
            EZViewReturn(app.CallingApp, app.tmsCurr, app.frameCurr, app.CallingAppHandles);
            
            %Re-enable EZView Button
            app.CallingApp.EZViewButton.Enable = "on";

            % Close EZView
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %re-enable EZView button in main app
            app.CallingApp.EZViewButton.Enable = "on";

            delete(app)
        end

        % Button pushed function: fwd
        function fwdButtonPushed(app, event)
            if app.frameCurr >= app.Nimages
                disp('Movie completed!')
                EZViewReturn(app.CallingApp);
                UIFigureCloseRequest(app,event);
            end

            % shift current timing/frame
            app.frameCurr = app.frameCurr + 1 ;
            app.tmsCurr = app.tvec(app.frameCurr) ;
            updateChangeFlags(app, 'all') ;
            
            updateDisplay(app);

        end

        % Button pushed function: back
        function backButtonPushed(app, event)
            app.frameCurr = max([app.frameCurr - 1,1]) ;
            app.tmsCurr = app.tvec(app.frameCurr) ;
            updateChangeFlags(app, 'all') ;
            
            updateDisplay(app);
        end

        % Button pushed function: ffwd
        function ffwdButtonPushed(app, event)
            if (app.frameCurr + 10) >= app.Nimages
                disp('Not enough remaining frames to skip forward')
                return
            end
            
            % shift current timing/frame
            app.frameCurr = app.frameCurr + 10 ;
            app.tmsCurr = app.tvec(app.frameCurr) ;
            updateChangeFlags(app, 'all') ;
            
            updateDisplay(app);
        end

        % Button pushed function: bback
        function bbackButtonPushed(app, event)
            app.frameCurr = max([app.frameCurr - 10,1]) ;
            app.tmsCurr = app.tvec(app.frameCurr) ;
            updateChangeFlags(app, 'all') ;

            updateDisplay(app);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'v'
                    bbackButtonPushed(app, event);
                case 'b'
                    backButtonPushed(app, event);
                case 'return'
                    fwdButtonPushed(app, event);
                case 'f'
                    ffwdButtonPushed(app, event);
                case 'downarrow'
                    bbackButtonPushed(app, event);
                case 'backarrow'
                    backButtonPushed(app, event);
                case 'rightarrow'
                    fwdButtonPushed(app, event);
                case 'uparrow'
                    ffwdButtonPushed(app, event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.strokeAxes = uiaxes(app.GridLayout2);
            title(app.strokeAxes, 'Stroke')
            app.rollAxes = uiaxes(app.GridLayout2);
            title(app.rollAxes, 'Roll')
            xlabel(app.rollAxes, 'X')
            ylabel(app.rollAxes, 'Y')
            zlabel(app.rollAxes, 'Z')
            app.LWingElevAxes = uiaxes(app.GridLayout2);
            title(app.LWingElevAxes, 'Left Wing Elevation')
            xlabel(app.LWingElevAxes, 'X')
            ylabel(app.LWingElevAxes, 'Y')
            zlabel(app.LWingElevAxes, 'Z')
            app.RWingElevAxes = uiaxes(app.GridLayout2);
            title(app.RWingElevAxes, 'Right Wing Elevation')
            xlabel(app.RWingElevAxes, 'X')
            ylabel(app.RWingElevAxes, 'Y')
            zlabel(app.RWingElevAxes, 'Z')
            app.LWingPitchAxes = uiaxes(app.GridLayout2);
            title(app.LWingPitchAxes, 'Left Wing Pitch')
            xlabel(app.LWingPitchAxes, 'X')
            ylabel(app.LWingPitchAxes, 'Y')
            zlabel(app.LWingPitchAxes, 'Z')
            app.RWingPitchAxes = uiaxes(app.GridLayout2);
            title(app.RWingPitchAxes, 'Right Wing Pitch')
            xlabel(app.RWingPitchAxes, 'X')
            ylabel(app.RWingPitchAxes, 'Y')
            zlabel(app.RWingPitchAxes, 'Z')
            app.RightPanel = uipanel(app.UIFigure);
            app.ReturntoGUIButton = uibutton(app.RightPanel, 'push');
            app.ReturntoGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoGUIButtonPushed, true);
            app.ReturntoGUIButton.Text = 'Return to GUI';
            app.back = uibutton(app.RightPanel, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Text = '<';
            app.fwd = uibutton(app.RightPanel, 'push');
            app.fwd.ButtonPushedFcn = createCallbackFcn(app, @fwdButtonPushed, true);
            app.fwd.Text = '>';
            app.bback = uibutton(app.RightPanel, 'push');
            app.bback.ButtonPushedFcn = createCallbackFcn(app, @bbackButtonPushed, true);
            app.bback.Text = '<<';
            app.ffwd = uibutton(app.RightPanel, 'push');
            app.ffwd.ButtonPushedFcn = createCallbackFcn(app, @ffwdButtonPushed, true);
            app.ffwd.Text = '>>';
            app.FrameInfoLabel = uilabel(app.UIFigure);
            app.FrameInfoLabel.Text = 'Frame Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = correctionGUI_EZView(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end